classdef HD_BET_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        EditMenu                   matlab.ui.container.Menu
        helponskullstrippingPanel  matlab.ui.container.Panel
        TextAreaLabel              matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        currentmoduleskullstrippingPanel  matlab.ui.container.Panel
        startButton                matlab.ui.control.Button
        saveparametersPanel        matlab.ui.container.Panel
        saveallButton              matlab.ui.control.Button
        save_maskDropDownLabel     matlab.ui.control.Label
        save_maskDropDown          matlab.ui.control.DropDown
        imagespathPanel            matlab.ui.container.Panel
        inputimagesTextAreaLabel   matlab.ui.control.Label
        inputimagesTextArea        matlab.ui.control.TextArea
        pathofinputimagesButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        Calling_app % 回调主APP
        %new
        input_images_paths%路径+文件名 字符向量元胞数组
        input_paths%路径 字符向量元胞数组
        is_save_mask%是否保存mask
        %new-end


    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Calling_app=mainapp;
            
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            
            if isempty(app.input_images_paths)
                alart_message='There is not the images to be processed!';
                uialert(app.UIFigure,alart_message,'Notice','Icon','error') 
            else
                %进度条显示处理进度
                progress_bar=uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Working on image 1','Value',0);
                device=py.str('cpu');
                mode=py.str('fast');
                pp=py.int(1);
                overwrite_existing=py.int(1);
                tta=py.int(0);
                for i=1:length(app.input_images_paths)
                    input_path=py.str(app.input_images_paths{i});
                    C=split(app.input_images_paths{i},'.');
                    
                    output_img=strcat(C{1},'_brain.nii.gz');
                    output_img=py.str(output_img);                    
                    py.HD_BET_start.HD_BET_run(input_path,output_img,mode,device,tta,app.is_save_mask,overwrite_existing,pp);
                     %进度条更新
                    progress_bar.Value=i/length(app.input_images_paths);
                    if i~=length(app.input_images_paths)
                        progress_bar.Message=sprintf('Working on image %d',i+1);
                    else%关闭进度条
                        close(progress_bar);
                    end
                end
                uialert(app.UIFigure,['Skull stripping is complete,' ...
                'and the output images are saved to the corresponding path.'],'Notice','Icon','success')
            end


        end

        % Button pushed function: saveallButton
        function saveallButtonPushed(app, event)
            app.output_prefix=app.filename_prefix_EditField.Value;   
            value = app.save_maskDropDown.Value;
            switch value
                case 'yes'
                    app.is_save_mask=py.int(1);
                case 'no'
                    app.is_save_mask=py.int(0);
            end
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success')
                
        end

        % Value changed function: save_maskDropDown
        function save_maskDropDownValueChanged(app, event)
            value = app.save_maskDropDown.Value;
            switch value
                case 'yes'
                    app.is_save_mask=py.int(1);
                case 'no'
                    app.is_save_mask=py.int(0);
            end
        end

        % Button pushed function: pathofinputimagesButton
        function pathofinputimagesButtonPushed2(app, event)
            [filename,file_path]=uigetfile({'*.nii.gz;*.nii'},'select One or More Files','MultiSelect', 'on');
            
            input_path_and_name=cell(1,length(filename));
            disp(filename)
            for i=1:length(filename)
                input_file_path=[file_path,filename{i}];
                input_path_and_name{i}=input_file_path;
            end
            
            app.input_images_paths=[app.input_images_paths,input_path_and_name];%包括了所有导入文件的路径+名字
            disp(app.input_images_paths)
            app.inputimagesTextArea.Value=app.input_images_paths;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.helponskullstrippingPanel = uipanel(app.UIFigure);
            app.helponskullstrippingPanel.Title = 'help on skull-stripping';
            app.TextAreaLabel = uilabel(app.helponskullstrippingPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.helponskullstrippingPanel);
            app.currentmoduleskullstrippingPanel = uipanel(app.UIFigure);
            app.currentmoduleskullstrippingPanel.Title = 'current module:skull-stripping';
            app.startButton = uibutton(app.currentmoduleskullstrippingPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.saveparametersPanel = uipanel(app.currentmoduleskullstrippingPanel);
            app.saveparametersPanel.Title = 'save parameters';
            app.saveallButton = uibutton(app.saveparametersPanel, 'push');
            app.saveallButton.ButtonPushedFcn = createCallbackFcn(app, @saveallButtonPushed, true);
            app.saveallButton.Text = 'save all';
            app.save_maskDropDownLabel = uilabel(app.saveparametersPanel);
            app.save_maskDropDownLabel.Text = 'save_mask';
            app.save_maskDropDown = uidropdown(app.saveparametersPanel);
            app.save_maskDropDown.Items = {'yes', 'no'};
            app.save_maskDropDown.ValueChangedFcn = createCallbackFcn(app, @save_maskDropDownValueChanged, true);
            app.save_maskDropDown.Value = 'yes';
            app.imagespathPanel = uipanel(app.currentmoduleskullstrippingPanel);
            app.imagespathPanel.Title = 'image(s) path';
            app.inputimagesTextAreaLabel = uilabel(app.imagespathPanel);
            app.inputimagesTextAreaLabel.Text = 'input image(s)';
            app.inputimagesTextArea = uitextarea(app.imagespathPanel);
            app.pathofinputimagesButton = uibutton(app.imagespathPanel, 'push');
            app.pathofinputimagesButton.ButtonPushedFcn = createCallbackFcn(app, @pathofinputimagesButtonPushed2, true);
            app.pathofinputimagesButton.Text = 'path of input images';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HD_BET_parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end