classdef MRF_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        helponfastfuzzyCmeansPanel    matlab.ui.container.Panel
        TextAreaLabel                 matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        currentmoduleFastFuzzyCMeansMarkovRandomFieldPanel  matlab.ui.container.Panel
        startButton                   matlab.ui.control.Button
        saveparametersPanel           matlab.ui.container.Panel
        saveallButton                 matlab.ui.control.Button
        FCMparammSliderLabel          matlab.ui.control.Label
        FCMparammSlider               matlab.ui.control.Slider
        FCMsegmentationcentersresultEditFieldLabel  matlab.ui.control.Label
        FCMsegmentationcentersresultEditField  matlab.ui.control.EditField
        imagespathPanel               matlab.ui.container.Panel
        Tree                          matlab.ui.container.Tree
        MatchedresultsNode            matlab.ui.container.TreeNode
        addimagesNode                 matlab.ui.container.TreeNode
        clearselectedimagesNode       matlab.ui.container.TreeNode
        vesselenhancementresultsNode  matlab.ui.container.TreeNode
        addimagesNode_2               matlab.ui.container.TreeNode
        clearselectedimagesNode_2     matlab.ui.container.TreeNode
        inputimagesTextArea           matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        Calling_app % 回调主APP
        %new
        matched_images_paths%直方图规定化图像路径+文件名 字符向量元胞数组
        ve_images_paths%x血管增强图像路径+文件名 字符向量元胞数组
        m_value%FCM参数：m
        %new-end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Calling_app=mainapp;
%             app.filename_prefix_EditField.Value="Please enter the prefix of the saved file";       
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            if isempty(app.matched_images_paths)
                alart_message='There is not the images to be processed!';
                uialert(app.UIFigure,alart_message,'Notice','Icon','error') 
            elseif length(app.matched_images_paths)~=length(app.ve_images_paths)%图片数量未对应
                alart_message="The number of 'matched images' are not equal with the number of " + ...
                    "'vessel enhancement images',please check again!";
                uialert(app.UIFigure,alart_message,'Notice','Icon','error') 
            else
                %进度条显示处理进度
                progress_bar=uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Working on image 1','Value',0);
                for i=1:length(app.matched_images_paths)
                    info=load_untouch_nii(app.matched_images_paths{i});
                    IMG=info.img;
                    hdr=info.hdr;
        %             K-means定初始聚类中心
                    k_value=4;
                    img=int32(IMG(IMG~=0));
        %             FCM
                    [C,U,LUT,H]=FastFCMeans(img(:),k_value,app.m_value,false) ;% perform FCM segmentation
                    disp('FCM segmentation centers are:' );
                    disp(C);
                    center='';
        %             在前端打印centers
                    for ii=1:length(C)
                        center=[center,num2str(C(ii))];
                        if ii~=length(C)
                            center=[center,','];
                        end
                    end
                    app.FCMsegmentationcentersresultEditField.Value=center;
                    L=LUT2label(img,LUT);
                    % Get FCM results获得FCM处理后的结果
                    FCM_seg=zeros(size(IMG));
                    OriginId=find(IMG~=0);
                    FCM_seg(OriginId(L(:)==k_value))=1;
                    if k_value>3
                        FCM_seg(OriginId(L(:)==k_value-1))=1;
                    end
                    % W is ratio of each class
                    W = zeros(1,k_value-1);
                    for c = 1:4
                        W(c) = length(find(L(:) == c)) / numel(IMG(IMG ~= 0));
                    end
                    output_img=make_nii(FCM_seg);
                    output_img.hdr = hdr; 
                    C=split(app.matched_images_paths{i},'.');
                    save_path=strcat(C{1},'_FCM.nii.gz');
                    save_nii(output_img, save_path);
                    %MRF开始
                    info_VE=load_untouch_nii(app.ve_images_paths{i});
                    vesselness=info_VE.img;
                    beta = 0.2;
                    IL = 10;
                    [MRF_seg] = MRF(FCM_seg, IMG, vesselness, U, beta, W, IL);
                    %% remove little islands
                    [MRF_seg_refine, ~, ~] = Connection_Judge_3D(MRF_seg, 0, [], 20, 3);
        
                    output_img=make_nii(MRF_seg_refine);
                    output_img.hdr = hdr; 
                    save_path=strcat(C{1},'_MRF.nii.gz');
                    save_nii(output_img, save_path);
                     %进度条更新
                    progress_bar.Value=i/length(app.matched_images_paths);
                    if i~=length(app.matched_images_paths)
                        progress_bar.Message=sprintf('Working on image %d',i+1);
                    else%关闭进度条
                        close(progress_bar);
                    end
                end
                uialert(app.UIFigure,['FFCM-MRF is complete,' ...
                'and the output images are saved to the corresponding path.'],'Notice','Icon','success')
        
            end
           
            
        end

        % Button pushed function: saveallButton
        function saveallButtonPushed(app, event)
            value = app.FCMparammSlider.Value;
            value_str=sprintf('%0.2f',value);
            app.m_value=str2double(value_str);
            disp(app.m_value)
            str_m=sprintf('m:%0.2f has been saved!',app.m_value);
            uialert(app.UIFigure,str_m,'Notice','Icon','success')
        end

        % Value changed function: FCMparammSlider
        function FCMparammSliderValueChanged(app, event)
            value = app.FCMparammSlider.Value;
            value_str=sprintf('%0.2f',value);
            app.m_value=str2double(value_str);
        end

        % Node expanded function: Tree
        function TreeNodeExpanded(app, event)
            node = event.Node;
            switch node.Text
                case 'Matched results'
                    if isempty(app.matched_images_paths)
                        app.inputimagesTextArea.Value='';
                    else
                        app.inputimagesTextArea.Value=app.matched_images_paths;
                    end
                    
                case 'vessel enhancement results'
                    if isempty(app.ve_images_paths)
                        app.inputimagesTextArea.Value='';
                    else
                        app.inputimagesTextArea.Value=app.ve_images_paths;
                    end
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            switch selectedNodes.Parent.Text
                case 'Matched results'
                    switch selectedNodes.Text
                        case 'add images'
                            [filename,file_path]=uigetfile({'*.nii.gz;*.nii'},'select One or More Files','MultiSelect', 'on');
                            input_path_and_name=cell(1,length(filename));
                            disp(filename)
                            for i=1:length(filename)
                                input_file_path=[file_path,filename{i}];
                                input_path_and_name{i}=input_file_path;
                            end
                            app.matched_images_paths=[app.matched_images_paths,input_path_and_name];%包括了所有导入文件的路径+名字
                            
                            app.inputimagesTextArea.Value=app.matched_images_paths;
                        case 'clear selected images'
                            app.matched_images_paths={};
                            app.inputimagesTextArea.Value='';
                    end
                case 'vessel enhancement results'
                    switch selectedNodes.Text
                        case 'add images'
                            [filename,file_path]=uigetfile({'*.nii.gz;*.nii'},'select One or More Files','MultiSelect', 'on');
                            input_path_and_name=cell(1,length(filename));
                            disp(filename)
                            for i=1:length(filename)
                                input_file_path=[file_path,filename{i}];
                                input_path_and_name{i}=input_file_path;
                            end
                            app.ve_images_paths=[app.ve_images_paths,input_path_and_name];%包括了所有导入文件的路径+名字 
                            app.inputimagesTextArea.Value=app.ve_images_paths;
                        case 'clear selected images'
                            app.ve_images_paths={};
                            app.inputimagesTextArea.Value='';
                    end
            end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.helponfastfuzzyCmeansPanel = uipanel(app.UIFigure);
            app.helponfastfuzzyCmeansPanel.Title = 'help on fast fuzzy C-means';
            app.TextAreaLabel = uilabel(app.helponfastfuzzyCmeansPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.helponfastfuzzyCmeansPanel);
            app.currentmoduleFastFuzzyCMeansMarkovRandomFieldPanel = uipanel(app.UIFigure);
            app.currentmoduleFastFuzzyCMeansMarkovRandomFieldPanel.Title = 'current module:Fast Fuzzy C Means-Markov Random Field';
            app.startButton = uibutton(app.currentmoduleFastFuzzyCMeansMarkovRandomFieldPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.saveparametersPanel = uipanel(app.currentmoduleFastFuzzyCMeansMarkovRandomFieldPanel);
            app.saveparametersPanel.Title = 'save parameters';
            app.saveallButton = uibutton(app.saveparametersPanel, 'push');
            app.saveallButton.ButtonPushedFcn = createCallbackFcn(app, @saveallButtonPushed, true);
            app.saveallButton.Text = 'save all';
            app.FCMparammSliderLabel = uilabel(app.saveparametersPanel);
            app.FCMparammSliderLabel.Text = 'FCM param:m';
            app.FCMparammSlider = uislider(app.saveparametersPanel);
            app.FCMparammSlider.Limits = [1.1 2.1];
            app.FCMparammSlider.MajorTicks = [1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1];
            app.FCMparammSlider.ValueChangedFcn = createCallbackFcn(app, @FCMparammSliderValueChanged, true);
            app.FCMparammSlider.Value = 1.1;
            app.FCMsegmentationcentersresultEditFieldLabel = uilabel(app.currentmoduleFastFuzzyCMeansMarkovRandomFieldPanel);
            app.FCMsegmentationcentersresultEditFieldLabel.Text = 'FCM segmentation centers result';
            app.FCMsegmentationcentersresultEditField = uieditfield(app.currentmoduleFastFuzzyCMeansMarkovRandomFieldPanel, 'text');
            app.imagespathPanel = uipanel(app.currentmoduleFastFuzzyCMeansMarkovRandomFieldPanel);
            app.imagespathPanel.Title = 'image(s) path';
            app.Tree = uitree(app.imagespathPanel);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.NodeExpandedFcn = createCallbackFcn(app, @TreeNodeExpanded, true);
            app.MatchedresultsNode = uitreenode(app.Tree);
            app.MatchedresultsNode.Text = 'Matched results';
            app.addimagesNode = uitreenode(app.MatchedresultsNode);
            app.addimagesNode.Text = 'add images';
            app.clearselectedimagesNode = uitreenode(app.MatchedresultsNode);
            app.clearselectedimagesNode.Text = 'clear selected images';
            app.vesselenhancementresultsNode = uitreenode(app.Tree);
            app.vesselenhancementresultsNode.Text = 'vessel enhancement results';
            app.addimagesNode_2 = uitreenode(app.vesselenhancementresultsNode);
            app.addimagesNode_2.Text = 'add images';
            app.clearselectedimagesNode_2 = uitreenode(app.vesselenhancementresultsNode);
            app.clearselectedimagesNode_2.Text = 'clear selected images';
            app.inputimagesTextArea = uitextarea(app.imagespathPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRF_parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end