classdef VE_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        EditMenu                  matlab.ui.container.Menu
        helponvesselenhancePanel  matlab.ui.container.Panel
        helpTextAreaLabel         matlab.ui.control.Label
        helpTextArea              matlab.ui.control.TextArea
        currentmodulevesselenhancePanel  matlab.ui.container.Panel
        startButton               matlab.ui.control.Button
        imagespathPanel           matlab.ui.container.Panel
        inputimagesTextAreaLabel  matlab.ui.control.Label
        inputimagesTextArea       matlab.ui.control.TextArea
        pathofinputimagesButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        Calling_app % 回调主APP
        %new
        input_images_paths%路径+文件名 字符向量元胞数组
        input_paths%路径 字符向量元胞数组
        %new-end
        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Calling_app=mainapp;
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            if isempty(app.input_images_paths)
                alart_message='There is not the images to be processed!';
                uialert(app.UIFigure,alart_message,'Notice','Icon','error') 
            else
                %进度条显示处理进度
                progress_bar=uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Working on image 1','Value',0);
                for i=1:length(app.input_images_paths)
                    info=load_untouch_nii(app.input_images_paths{i});
                    IMG=info.img;
                    hdr=info.hdr;
        %             血管增强处理
                    spacing=hdr.dime.pixdim(2:4);%分辨率
        %             disp(spacing)
                    I = IMG - min(IMG(:));
                    I = I / prctile(I(I(:) > 0.5 * max(I(:))),90);
                    I(I>1) = 1;
                    voxSpace=min(spacing);%单位体素
                %     scales=[voxSpace:voxSpace:10*voxSpace]; %For Human
                %     scales=[voxSpace:voxSpace:5*voxSpace]; % For External dataset        
                    tau = 1;
                    vesselness = vesselness3D(I, [0.5:0.5:3], [1;1;1], tau, true);
                %     vesselness = vesselness3D(I, [0.5:0.5:3], spacing, tau, true);
                    Mask=imbinarize(IMG);
                    Mask=double(Mask);
                    
                    vesselness=vesselness.*Mask;
        
                    output_img=make_nii(vesselness);
                    output_img.hdr = hdr; 
                    C=split(app.input_images_paths{i},'.');
                    
                    
                    save_path=strcat(C{1},'_ve.nii.gz');
                    save_nii(output_img, save_path);
                    
                     %进度条更新
                    progress_bar.Value=i/length(app.input_images_paths);
                    if i~=length(app.input_images_paths)
                        progress_bar.Message=sprintf('Working on image %d',i+1);
                    else%关闭进度条
                        close(progress_bar);
                    end
                end
               uialert(app.UIFigure,['Vessel enhance is complete,' ...
                'and the output images are saved to the corresponding path.'],'Notice','Icon','success')
            end


        end

        % Button pushed function: pathofinputimagesButton
        function pathofinputimagesButtonPushed2(app, event)
            text_input_images="a button to for importing the image(s) to be processed.";
            app.helpTextArea.Value=sprintf('%s\n',text_input_images);
            
            
            [filename,file_path]=uigetfile({'*.nii.gz;*.nii'},'select One or More Files','MultiSelect', 'on');
            
            input_path_and_name=cell(1,length(filename));
            disp(filename)
            for i=1:length(filename)
                input_file_path=[file_path,filename{i}];
                input_path_and_name{i}=input_file_path;
            end
            
            app.input_images_paths=[app.input_images_paths,input_path_and_name];%包括了所有导入文件的路径+名字
            disp(app.input_images_paths)
            app.inputimagesTextArea.Value=app.input_images_paths;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.helponvesselenhancePanel = uipanel(app.UIFigure);
            app.helponvesselenhancePanel.Title = 'help on vessel enhance';
            app.helpTextAreaLabel = uilabel(app.helponvesselenhancePanel);
            app.helpTextAreaLabel.Text = 'help';
            app.helpTextArea = uitextarea(app.helponvesselenhancePanel);
            app.currentmodulevesselenhancePanel = uipanel(app.UIFigure);
            app.currentmodulevesselenhancePanel.Title = 'current module:vessel enhance';
            app.startButton = uibutton(app.currentmodulevesselenhancePanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.imagespathPanel = uipanel(app.currentmodulevesselenhancePanel);
            app.imagespathPanel.Title = 'image(s) path';
            app.inputimagesTextAreaLabel = uilabel(app.imagespathPanel);
            app.inputimagesTextAreaLabel.Text = 'input image(s)';
            app.inputimagesTextArea = uitextarea(app.imagespathPanel);
            app.pathofinputimagesButton = uibutton(app.imagespathPanel, 'push');
            app.pathofinputimagesButton.ButtonPushedFcn = createCallbackFcn(app, @pathofinputimagesButtonPushed2, true);
            app.pathofinputimagesButton.Text = 'path of input images';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VE_parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end