classdef hist_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        currentmodulehistogrammatchingPanel  matlab.ui.container.Panel
        imageButtonGroup                matlab.ui.container.ButtonGroup
        imagetohistogrammatchingButton  matlab.ui.control.ToggleButton
        image_to_histogram_matching_EditField  matlab.ui.control.EditField
        pathofsaveimageButton           matlab.ui.control.ToggleButton
        path_of_save_image_EditField    matlab.ui.control.EditField
        startButton                     matlab.ui.control.Button
        parametersPanel                 matlab.ui.container.Panel
        setthetragethistogramDropDownLabel  matlab.ui.control.Label
        setthetragethistogramDropDown   matlab.ui.control.DropDown
        outputfilenameEditFieldLabel    matlab.ui.control.Label
        filename_prefix_EditField       matlab.ui.control.EditField
        saveallButton                   matlab.ui.control.Button
        helponhistogrammatchingPanel    matlab.ui.container.Panel
        TextAreaLabel                   matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        Calling_app % 回调主APP
        target_hist
        input_img_path
        output_img
        output_prefix
        output_path
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Calling_app=mainapp;
            app.filename_prefix_EditField.Value="Please enter the prefix of the saved file";
            
        end

        % Selection changed function: imageButtonGroup
        function imageButtonGroupSelectionChanged(app, event)
            selectedButton = app.imageButtonGroup.SelectedObject;
%             app.filename_prefix_EditField.Value="请输入保存文件的前缀,后点击'filename prefix'";
            switch selectedButton.Text
                case 'image to histogram matching'
                    [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
                    app.image_to_histogram_matching_EditField.Value=[path,filename];
                    app.input_img_path=app.image_to_histogram_matching_EditField.Value;
                    app.TextArea.Value="请单击按钮'image to histogram matching'导入要进行直方图规定化的图像，仅限于NIFTI格式图像";
                case 'path of save image'
                    app.output_path = uigetdir('C:\Users\86133\Documents\MATLAB');
                    app.path_of_save_image_EditField.Value=app.output_path;
            end
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            Trans_Image=histMatching(app.input_img_path,app.target_hist);
            app.output_img = make_nii(Trans_Image);
            input_data=load_untouch_nii(app.input_img_path);
            app.output_img.hdr = input_data.hdr;
            path=[app.output_path,'\',app.output_prefix,'.nii.gz'];
            save_nii(app.output_img, path);
            uialert(app.UIFigure,['Histogram matching is complete,' ...
                'and the output image is saved to the corresponding path.'],'Notice','Icon','success')
            
        end

        % Value changed function: setthetragethistogramDropDown
        function setthetragethistogramDropDownValueChanged(app, event)
            value = app.setthetragethistogramDropDown.Value;
            switch value
                case 'MIDAS'
                    app.target_hist='Target_Hist.mat';
                case 'TubeTK'%暂未导入，导入后取消警告
                    app.target_hist='Target_Hist_TubeTK.mat';
                    uialert(app.UIFigure,'The group histogram of the TubeTK is not configured!','Notice','Icon','error') 
                otherwise   
            end
        end

        % Button pushed function: saveallButton
        function saveallButtonPushed(app, event)
            value = app.setthetragethistogramDropDown.Value;
            switch value
                case 'MIDAS'
                    app.target_hist='Target_Hist.mat';
                case 'TubeTK'%暂未导入，导入后取消警告
                    app.target_hist=Target_Hist_TubeTK;
                    uialert(app.UIFigure,'The group histogram of the TubeTK is not configured!','Notice','Icon','error') 
                otherwise   
            end
            app.output_prefix=app.filename_prefix_EditField.Value;
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success') 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.currentmodulehistogrammatchingPanel = uipanel(app.UIFigure);
            app.currentmodulehistogrammatchingPanel.Title = 'current module:histogram matching';
            app.imageButtonGroup = uibuttongroup(app.currentmodulehistogrammatchingPanel);
            app.imageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @imageButtonGroupSelectionChanged, true);
            app.imageButtonGroup.Title = 'image';
            app.imagetohistogrammatchingButton = uitogglebutton(app.imageButtonGroup);
            app.imagetohistogrammatchingButton.Text = 'image to histogram matching';
            app.imagetohistogrammatchingButton.Value = true;
            app.image_to_histogram_matching_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.pathofsaveimageButton = uitogglebutton(app.imageButtonGroup);
            app.pathofsaveimageButton.Text = 'path of save image';
            app.path_of_save_image_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.startButton = uibutton(app.currentmodulehistogrammatchingPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.parametersPanel = uipanel(app.currentmodulehistogrammatchingPanel);
            app.parametersPanel.Title = 'parameters';
            app.setthetragethistogramDropDownLabel = uilabel(app.parametersPanel);
            app.setthetragethistogramDropDownLabel.Text = 'set the traget histogram';
            app.setthetragethistogramDropDown = uidropdown(app.parametersPanel);
            app.setthetragethistogramDropDown.Items = {'MIDAS', 'TubeTK', 'Option 3', 'Option 4'};
            app.setthetragethistogramDropDown.ValueChangedFcn = createCallbackFcn(app, @setthetragethistogramDropDownValueChanged, true);
            app.setthetragethistogramDropDown.Value = 'MIDAS';
            app.outputfilenameEditFieldLabel = uilabel(app.parametersPanel);
            app.outputfilenameEditFieldLabel.Text = 'output filename';
            app.filename_prefix_EditField = uieditfield(app.parametersPanel, 'text');
            app.saveallButton = uibutton(app.parametersPanel, 'push');
            app.saveallButton.ButtonPushedFcn = createCallbackFcn(app, @saveallButtonPushed, true);
            app.saveallButton.Text = 'save all';
            app.helponhistogrammatchingPanel = uipanel(app.UIFigure);
            app.helponhistogrammatchingPanel.Title = 'help on histogram matching';
            app.TextAreaLabel = uilabel(app.helponhistogrammatchingPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.helponhistogrammatchingPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hist_parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end